classdef twoDimPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MinimizatorUIFigure            matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        tabMain                        matlab.ui.container.Tab
        MainAxes                       matlab.ui.control.UIAxes
        DimAxes                        matlab.ui.control.UIAxes
        ResTable                       matlab.ui.control.Table
        IterationsPanel                matlab.ui.container.Panel
        Spinner                        matlab.ui.control.Spinner
        SliderIter                     matlab.ui.control.Slider
        MaxSpinnerLabel                matlab.ui.control.Label
        MaxSpinner                     matlab.ui.control.Spinner
        StepSpinnerLabel               matlab.ui.control.Label
        StepSpinner                    matlab.ui.control.Spinner
        ManipulatePanel                matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ZoomButton                     matlab.ui.control.ToggleButton
        PanButton                      matlab.ui.control.ToggleButton
        ContourColorsCheckBox          matlab.ui.control.CheckBox
        ClearAllButton                 matlab.ui.control.Button
        ContoursSliderLabel            matlab.ui.control.Label
        ContoursSlider                 matlab.ui.control.Slider
        Info                           matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TestSpinner                    matlab.ui.control.Spinner
        tab3D                          matlab.ui.container.Tab
        Axes3D                         matlab.ui.control.UIAxes
        SliderIter3D                   matlab.ui.control.Slider
        IterationsSpinnerLabel         matlab.ui.control.Label
        IterationsSpinner              matlab.ui.control.Spinner
        InfoPanel                      matlab.ui.container.Panel
        funcPanel                      matlab.ui.container.Panel
        FunctionDropDownLabel          matlab.ui.control.Label
        FunctionDropDown               matlab.ui.control.DropDown
        StartPointPanel                matlab.ui.container.Panel
        X1SpinnerLabel                 matlab.ui.control.Label
        X1Spinner                      matlab.ui.control.Spinner
        X2SpinnerLabel                 matlab.ui.control.Label
        X2Spinner                      matlab.ui.control.Spinner
        DefaultParametersModeCheckBox  matlab.ui.control.CheckBox
        Panel                          matlab.ui.container.Panel
        CalculateMinimumButton         matlab.ui.control.Button
        LineSearchPanel                matlab.ui.container.Panel
        EnableLine                     matlab.ui.control.CheckBox
        MethodDropDown_2Label          matlab.ui.control.Label
        LineDropDown                   matlab.ui.control.DropDown
        EditField_2Label               matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.NumericEditField
        startEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        MEditFieldLabel                matlab.ui.control.Label
        MEditField                     matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        RhoEditField                   matlab.ui.control.NumericEditField
        StopConditionsPanel            matlab.ui.container.Panel
        EnableStop                     matlab.ui.control.CheckBox
        IterationsEditFieldLabel       matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel          matlab.ui.control.Label
        EpsilonEditField               matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel        matlab.ui.control.Label
        PrecisionEditField             matlab.ui.control.NumericEditField
        GroupDropDownLabel             matlab.ui.control.Label
        GroupDropDown                  matlab.ui.control.DropDown
        MethodDropDown_12Label         matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        start % function drawn indicator
        
        guiHelper % helper class
        startingPoints % starting points
        presets % method and line search presets
        infos % contains plot infos
        
        defaultMode % deafult
        
        contNumber % contour lines multiplayer
        contNative % contour lines defined by function
        
        plotData % Plot paths
        plotLimits % Current plot limits
        functionName % Description
        
        plotHeads % Plot last point
        plotHeads3D % Plot 3D last point
        plotHandles % Plot handles main2D
        plotHandlesBig3D % Plot handles big3D
        
        plotDataZ % Data for Z of 3D plot
        
        plotBig3D % contours of big 3D
        
        mainColors % colors for plots
        colorIndex % global color index
        
        functionHandle % Current function
        
        startX1 % x coord
        startX2 % y coord
        
        functionShown % Check if function is drawn
        
        % data
        plotNumber % number of drawn methods
        contMain % Main axes contour data
    end

    methods (Access = private)
    
        
        function calculateMinimum(app)
            % set start
            app.start = true;
            
            % calculate 
            SELECTED_METHOD = str2func(app.MethodDropDown.Value);
            SELECTED_FUNCTION = app.FunctionDropDown.Value;
            methodParams = collectParams(app);
            [ fmin, xmin, iterNum, cpuTime, evalNumbers, valuesPerIter ] = SELECTED_METHOD(SELECTED_FUNCTION, methodParams);
            results = Results(fmin, xmin, valuesPerIter.gradientPerIteration(end), iterNum, cpuTime, evalNumbers, valuesPerIter);

            % draw function if not drawn
            if (not (app.functionShown))
                % clear data and plot
                clearAll(app);
                % add row to table
                addToTable(app, results);
                % draw function
                drawFunction(app);
            else
                % add row to table
                addToTable(app, results);
            end

            % path
            xVec = results.valuesPerIteration.xPerIteration;
            
            % set data
            app.plotData{app.plotNumber} = xVec;
            app.plotHeads{app.plotNumber} = xVec(1:2,end);
            
            % get color
            ind = mod(app.colorIndex, size(app.mainColors, 1)) + 1;
            color = app.mainColors(ind, :);
            
            % set sliders
            setMaxSlider(app);
            setSlider(app);
            
            % method legend name
            lineName = '';
            if strcmp(app.GroupDropDown.Value, 'TrustRegion')
                lineName = 'TrustRegion';
            else
                lineName = app.LineDropDown.Value;
            end
            methodLegend = strrep(app.MethodDropDown.Value, '_', '') + " - " + lineName;
            
            % draw main 2D
            hold(app.MainAxes, 'on');
            app.plotHandles(app.plotNumber) = plot(app.MainAxes, xVec(1,:), xVec(2,:), "-o",...
                'LineWidth', 2.5,...
                'MarkerSize', 3.5,...
                'DisplayName', methodLegend,...
                'MarkerFaceColor', color,...
                'Color', color...
            );
            hold(app.MainAxes, 'off');
            
            % draw heads
            hold(app.MainAxes, 'on');
            app.plotHeads{app.plotNumber} = plot(app.MainAxes, xVec(1,end), xVec(2,end), "o",...
                'LineWidth', 1.5,...
                'MarkerSize', 10,...
                'Color', color,...
                'MarkerFaceColor', color,...
                'HandleVisibility','off',...
                'MarkerEdgeColor', 'w'...
            );
            hold(app.MainAxes, 'off');
            
            % draw big 3D
            
            % up factor
            up = 0;
            
            % set Z
            n = size(xVec, 2);
            Z = zeros(1, n);
            for i = 1:n
               Z(i) = up + app.functionHandle([xVec(1,i) xVec(2,i)], [1 0 0]);
            end
            % save Z for iterations slider
            app.plotDataZ{app.plotNumber} = Z;
            
            hold(app.Axes3D, 'on');
            app.plotHandlesBig3D(app.plotNumber) = plot3(app.Axes3D, xVec(1,:), xVec(2,:), Z(:) ,"-o",...
              'LineWidth', 2,...
              'MarkerSize', 4,....
              'DisplayName', methodLegend,...
              'MarkerFaceColor', color,...
              'Color', color...
            );
            hold(app.Axes3D, 'off');
            
            % draw 3D heads
            hold(app.Axes3D, 'on');
            app.plotHeads3D{app.plotNumber} = scatter3(app.Axes3D, xVec(1,end), xVec(2,end), Z(end), 60,... 
                'LineWidth', 1,...
                'MarkerFaceColor', color,...
                'HandleVisibility','off',...
                'MarkerEdgeColor', 'w'...
            );
            hold(app.Axes3D, 'off');

            % redraw all functions to max iters
            redrawPlots(app, app.Spinner.Limits(2) + 1);

            % increase plot index
            app.plotNumber = app.plotNumber + 1;
            app.colorIndex = app.colorIndex + 1;
        end
        
        function drawFunction(app)
            % draw function
            functionName = app.FunctionDropDown.Value;
            limitAxes = app.infos(functionName).Limits;
            app.contNative = app.infos(functionName).Contours;
            
            % prepare for draw
            lbx = limitAxes(1);
            ubx = limitAxes(2);
            lby = limitAxes(3);
            uby = limitAxes(4);
            
            % set current plot limits
            app.plotLimits = limitAxes;
            
            % set X Y
            n = 200;
            x = linspace(lbx, ubx, n);
            y = linspace(lby, uby, n);
            [X,Y] = meshgrid(x,y);
            Z = zeros(n, n);
            
            % set Z
            selFunc = str2func(functionName);
            app.functionHandle = selFunc;
            for i = 1:n
               for j = 1:n
                  Z(i, j) = selFunc([X(i, j) Y(i, j)], [1 0 0]); 
               end
            end
            
            % Minimum Z
            mins = app.infos(functionName).Minimum;
            n = size(mins, 1);
            minsZ = zeros(n, 1);
            for i = 1:n
                minsZ(i) = selFunc([mins(i, 1) mins(i, 2)], [1 0 0]);
            end
            
            % contour levels
            levVal = logspace(-0.5,5,50);
            
            % draw main 2D
            [~, app.contMain] = contourf(app.MainAxes, X, Y, Z, levVal, 'DisplayName', functionName);
            axis(app.MainAxes, [lbx ubx lby uby]);
            % min main
            hold(app.MainAxes, 'on');
            plot(app.MainAxes, mins(:,1), mins(:,2), 'wo', 'MarkerSize', 16, 'LineWidth', 2, 'MarkerFaceColor',[0 0 0], 'HandleVisibility','off');
            hold(app.MainAxes, 'off');
            % legend
            legend(app.MainAxes, 'Location','northwest');
            % color
            colormap(app.MainAxes, parula);
            % alpha
            
            
            % draw main 3D
            st = 10;
            XX = X(1:st:end, 1:st:end);
            YY = Y(1:st:end, 1:st:end);
            ZZ = Z(1:st:end, 1:st:end);
            plotm3D = surf(app.DimAxes, XX, YY, ZZ);
            colormap(app.DimAxes, autumn);

            % draw big 3D
            ec = 0.6;
            % 'edgecolor',[ec ec ec],
            app.plotBig3D = surf(app.Axes3D, XX, YY, ZZ,'DisplayName', functionName); %'LineWidth', 2); %'edgecolor', [100 100 100]/255);
            legend(app.Axes3D, 'Location','northwest');
            shading(app.Axes3D, 'interp');
            %colormap(app.Axes3D, gray);
            %alpha(app.plotBig3D, .2);
            %hidden(app.Axes3D,'on');
            
            % min 3D
            mins = app.infos(functionName).Minimum;
            hold(app.Axes3D, 'on');
            scatter3(app.Axes3D, mins(:,1), mins(:,2), minsZ(:), 150, 'DisplayName', 'Minimum',...
                'LineWidth', 2,...
                'MarkerEdgeColor','w',...
                'MarkerFaceColor','k');
            hold(app.Axes3D, 'off');
            
            % reset contours
            app.contNumber = 0;
            app.ContoursSlider.Value = 0;
            app.colorIndex = 0;
            app.StepSpinner.Value = 1;
            app.Spinner.Step = 1;
            app.IterationsSpinner.Step = 1;
            app.ContourColorsCheckBox.Value = true;

            % adapt
            adapt(app);

            % set function as shown
            app.functionShown = true;
            
        end
        
        % set methods drop down menu based on methods group
        function setMethodsFromGroup(app, methodGroup)
            methods = strcat('Methods', filesep, 'MultiDimensional', filesep, methodGroup);
            multMethods = dir(methods);
            app.MethodDropDown.Items = app.guiHelper.fNames(multMethods);
            
            % set inital method
            method = app.presets.getMethod(methodGroup);
            if strcmp(method, '') ~= 1
               app.MethodDropDown.Value = method; 
            end
        end
        
        % set default line search
        function setLineSearch(app, methodGroup, method)
            % set initial line search
            line = app.presets.getLineSearch(method, methodGroup);
            if strcmp(line, '') ~= 1
               app.LineDropDown.Value = line; 
            end
            
            % set params
            setLineSearchParams(app, app.LineDropDown.Value);
        end
        
        % set line search params
        function setLineSearchParams(app, lineSearch)
            params = app.presets.getLineSearchParams(lineSearch);
            app.BetaEditField.Value = params.('beta');
            app.StartEditField.Value = params.('starting_point');
            app.MEditField.Value = params.('M');
            app.SigmaEditField.Value = params.('sigma');
            app.RhoEditField.Value = params.('rho');
        end
        
        
        % set function default starting point
        function setStartPoint(app, funcName)
            values = app.startingPoints(funcName);
            app.X1Spinner.Value = values(1);
            app.X2Spinner.Value = values(2);
            app.startX1 = values(1);
            app.startX2 = values(2);
        end
        
        function methodParams = collectParams(app)
            STARTING_POINT = [app.startX1 app.startX2];
            STEP_SIZE = 0.01;
            DIMENSION = 2;
            NUMBER_OF_STEPS = app.IterationsEditField.Value;
            DELTA_MIN = 0.001;
            OD = 0;
            DO = 100;
            EPS = app.EpsilonEditField.Value;
            WORKPREC = app.PrecisionEditField.Value;
            BETA = app.BetaEditField.Value;
            SIGMA = app.SigmaEditField.Value;
            RHO = app.RhoEditField.Value;
            KSI = 1e-14;
            EM = app.MEditField.Value;
            STARTINGPOINT = app.StartEditField.Value;
            LINE_SEARCH_METHOD = str2func(app.LineDropDown.Value);
            methodParams  = MathodParams(STARTING_POINT, STEP_SIZE, DIMENSION, NUMBER_OF_STEPS, EPS, WORKPREC, DELTA_MIN, OD, DO, BETA, SIGMA, RHO, EM, KSI, STARTINGPOINT, LINE_SEARCH_METHOD);
        end
        
        % add row to table
        function addToTable(app, result)
            % find line search name
            lineName = '';
            if strcmp(app.GroupDropDown.Value, 'TrustRegion')
                lineName = 'TrustRegion';
            else
                lineName = app.LineDropDown.Value;
            end
            
            % data to insert
            newData = {
                true
                false
                app.MethodDropDown.Value
                app.GroupDropDown.Value
                lineName
                result.fmin
                char(strcat(num2str(result.xmin(1)), ", ", num2str(result.xmin(2))))
                result.gradNorm
                result.iterNum
                result.cpuTime
                result.evalNumbers.functionEvalNo
                result.evalNumbers.gradientEvalNo
                result.evalNumbers.hessianEvalNo
            };
        
            % fill row
            pause(0.2);
            app.ResTable.Data(end+1,:) = newData;
            pause(0.2);
        end
        
        % remove row from table
        function remFromTable(app, row)
            % remove row from table
            app.ResTable.Data(row,:) = [];
        end
        
        % hide row from plot
        function hideFromPlot(app, row, state)
            if (state == 1)
                set(app.plotHandles(row),'visible','on');
                set(app.plotHandlesBig3D(row),'visible','on');
                set(app.plotHeads{row},'visible','on');
                set(app.plotHeads3D{row},'visible','on');
            else
                set(app.plotHandles(row),'visible','off');
                set(app.plotHandlesBig3D(row),'visible','off');
                set(app.plotHeads{row},'visible','off');
                set(app.plotHeads3D{row},'visible','off');
            end
        end
        
        % remove method from plots
        function clearPlot(app, row)
            % delete data
            app.plotData(row) = [];
            app.plotDataZ(row) = [];
            % delete plot
            delete(app.plotHandles(row));
            delete(app.plotHandlesBig3D(row));
            delete(app.plotHeads{row});
            delete(app.plotHeads3D{row});
            app.plotHandles(row) = [];
            app.plotHandlesBig3D(row) = [];
            app.plotHeads(row) = [];
            app.plotHeads3D(row) = [];
            % decrease plot number
            app.plotNumber = app.plotNumber - 1;
            % fix slider
            if (app.plotNumber ~= 1)
                setMaxSlider(app);
                setSlider(app);
            end
            % fix plot
            redrawPlots(app, app.Spinner.Limits(2) + 1);
        end
        
        % clear all methods and plots
        function clearAll(app)
            % clear plot if needed
            for i = 1:(app.plotNumber - 1)
               delete(app.plotHandles(i));
               delete(app.plotHandlesBig3D(i));
               delete(app.plotHeads{i});
               delete(app.plotHeads3D{i});
            end
            % reset data
            app.ResTable.Data = {};
            app.plotHandles = [];
            app.plotHandlesBig3D = [];
            app.plotData = [];
            app.plotDataZ = [];
            app.plotHeads = [];
            app.plotHeads3D = [];
            app.plotNumber = 1;
            app.functionShown = false;
        end
        
        % set maximum iters slider
        function setMaxSlider(app)
             up = max(3, max(cellfun(@(x) (size(x, 2)), app.plotData)));
             app.MaxSpinner.Limits = [1, up - 1];
             app.MaxSpinner.Value = up - 1;
        end
        
        % set slider value
        function setSlider(app)
            upper = app.MaxSpinner.Value;
            % set limits
            app.SliderIter.Limits = [0, upper];
            app.SliderIter3D.Limits = [0, upper];
            app.Spinner.Limits = [0, upper];
            app.IterationsSpinner.Limits = [0, upper];
            % set values
            app.Spinner.Value = upper;
            app.SliderIter.Value = upper;
            app.IterationsSpinner.Value = upper;
            app.SliderIter3D.Value = upper;
        end
        
        % redraw plots with custom iteration
        function redrawPlots(app, it)
            % update main 2D
            n = size(app.plotData, 2);
            for i = 1:n
               % points
               data = app.plotData{i};
               dataZ = app.plotDataZ{i};
               dataN = size(data, 2);
               cap = min(it, dataN);
               set(app.plotHandles(i),'XData',data(1,1:cap),'YData',data(2,1:cap));
               set(app.plotHandlesBig3D(i),'XData',data(1,1:cap),'YData',data(2,1:cap), 'ZData', dataZ(1:cap));
               % heads
               set(app.plotHeads{i},'XData',data(1,cap),'YData',data(2,cap));
               set(app.plotHeads3D{i},'XData',data(1,cap),'YData',data(2,cap), 'ZData', dataZ(cap));
            end
        end
        
        % reset zoom level on all plots
        function resetZoom(app)
            % check if function is drawn
            if ~app.start
               return; 
            end
            
            % reset zoom
            axis(app.MainAxes, app.plotLimits(1:4));
            % reset contours
            app.contNumber = 0;
            app.ContoursSlider.Value = 0;
            % adapt
            adapt(app);
        end
        
        % adapt plot wen zooming
        function adapt(app)
            % check if function is drawn
            if ~app.start
               return; 
            end
            
            % adapt 2D
            uby = app.MainAxes.YLim(2);
            lbx = app.MainAxes.XLim(1);
            lby = app.MainAxes.YLim(1);
            ubx = app.MainAxes.XLim(2);
            numConLine = 200;
            
            x = linspace(lbx, ubx, numConLine);
            y = linspace(lby, uby, numConLine);
            [X,Y] = meshgrid(x,y);
            n = numConLine;
            Z = zeros(n, n);
            for i = 1:n
               for j = 1:n
                  Z(i, j) = app.functionHandle([X(i, j) Y(i, j)], [1 0 0]); 
               end
            end
            
            app.contMain.XData = X;
            app.contMain.YData = Y;
            app.contMain.ZData = Z;
            
            mini = min(Z(:));
            maxi = max(Z(:));
            
            % calculate zoom factor
            factX = (ubx - lbx) / (app.plotLimits(2) - app.plotLimits(1));
            factY = (uby - lby) / (app.plotLimits(4) - app.plotLimits(3));
            zoomF = factX * factY;
            
            % contour zoom
            contZoom = 1;
            factor = 100;
            if app.contNumber < 0
                contZoom = -app.contNumber * factor + 1;
            else
                contZoom = 1 / (app.contNumber * factor + 1);
            end
            
            % contour native zoom
            contNativeZoom = 1;
            if app.contNative < 0
                contNativeZoom = -app.contNative * factor + 1;
            else
                contNativeZoom = 1 / (app.contNative * factor + 1);
            end
            
            % lines
            s = -2;
            conts = (exp((s:0.5:20)) - exp(s));
            levVal = conts * contNativeZoom * contZoom * zoomF + mini; % - abs(mini)*0.1;
            app.contMain.LevelList = levVal;
            
            % adapt mini 3D
            st = 10;
            XX = X(1:st:end, 1:st:end);
            YY = Y(1:st:end, 1:st:end);
            ZZ = Z(1:st:end, 1:st:end);
            
            % show mini 3D
            surf(app.DimAxes, XX, YY, ZZ);
            
            % adapt big 3D
%             st = 5;
%             XX = X(1:st:end, 1:st:end);
%             YY = Y(1:st:end, 1:st:end);
%             ZZ = Z(1:st:end, 1:st:end);
            
            % show big 3D
            axis(app.Axes3D, [lbx ubx lby uby mini maxi]);
            set(app.plotBig3D,'XData',X,'YData',Y, 'ZData',Z);
        end
        
        function ClipMe(app)
            adapt(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function onCreate(app)
            % set window position
            dw = 1280;
            dh = 720;
            ss = get( groot, 'Screensize');
            app.MinimizatorUIFigure.Position = [100 (ss(4) - dh - 100) dw dh];
            
            % set colors
%             app.mainColors = [
%                                 [1    0.6    0];
%                                 [197, 66, 244] / 255;
%                                 [0.9 0.1 0.3];
%                                 [97 216 84] / 255;
%                                 [255, 227, 45] / 255;
%                                 [142, 35, 0] / 255;
%                               ];
            app.mainColors = [
                                [253 83 8] / 255; % red
                                [255 228 52] / 255; % gold
                                [58 168 50] / 255; % green
                                [173 0 254] / 255; % blue
                                [167 25 75] / 255; % violet
                                [10 10 10] / 255 % white
                             ];            

            app.colorIndex = 0;
            
            % set helper data
            app.guiHelper = GuiHelpers();
            app.startingPoints = StartingPointGenerator(2);
            app.presets = presets();
            app.infos = PlotInfoGenerator();
            app.defaultMode = true;
            
            % initialize data in functions dropdown 
            functions=dir('Functions/MultiDimensional');
            app.FunctionDropDown.Items = app.guiHelper.fNames(functions);
            % remove functions not for two dims
            app.FunctionDropDown.Items(ismember(app.FunctionDropDown.Items, ['BDQRTIC'])) = [];
            app.FunctionDropDown.Items(ismember(app.FunctionDropDown.Items, ['DQDRTIC'])) = [];
            app.FunctionDropDown.Items(ismember(app.FunctionDropDown.Items, ['ExtPowell'])) = [];
            app.FunctionDropDown.Items(ismember(app.FunctionDropDown.Items, ['NONDQUAR'])) = [];
            
            % set for testing himmel
            app.FunctionDropDown.Value = 'ExtHimmelblau';
            
            % initialze data in line search dropdown
            lineSearchMethods=dir('Methods/MultiDimensional/LineSearch');
            app.LineDropDown.Items = app.guiHelper.fNames(lineSearchMethods);
            
            % initialize data in method group dropdown 
            methodGroups = dir('Methods/MultiDimensional');
            methodGroups = methodGroups(strcmp({methodGroups.name},'LineSearch') == 0);
            app.GroupDropDown.Items = app.guiHelper.subDirs(methodGroups);
            
            % initialize data in method dropdown 
            setMethodsFromGroup(app, app.GroupDropDown.Value);
            
            % set default line search
            setLineSearch(app, app.GroupDropDown.Value, app.MethodDropDown.Value);
            
            % set function start point
            setStartPoint(app, app.FunctionDropDown.Value);
            
            % add table formating
            app.ResTable.ColumnFormat = {'logical' 'logical' 'char' 'char' 'char'  'numeric' 'numeric'};
            app.ResTable.ColumnEditable = [true, true, false, false, false, false, false, false, false, false, false, false, false];

            % set data
            app.functionShown = false;
            app.plotNumber = 1;
            app.contNumber = 0;
            app.start = false;
            
            % resize
            app.InfoPanel.AutoResizeChildren = 'on';
            app.tabMain.AutoResizeChildren = 'on';
            app.tab3D.AutoResizeChildren = 'on';
            app.ManipulatePanel.AutoResizeChildren = 'on';
            %app.ManipulatePanel.AutoResizeChildren = 'on';
            app.IterationsPanel.AutoResizeChildren = 'on';
            
            % plot operation
            zoom(app.MainAxes, 'on');
            %rotate3d(app.DimAxes, 'on');
            rotate3d(app.Axes3D, 'on');
            % pan
            
            % add event on zoom
            warning('off', 'MATLAB:structOnObject');
            addlistener(struct(app.MainAxes).Axes,'YLim','PostSet', @(src,evt)ClipMe(app));
        end

        % Button pushed function: CalculateMinimumButton
        function CalculateMinimumButtonPushed(app, event)
            calculateMinimum(app);
        end

        % Size changed function: MinimizatorUIFigure
        function MinimizatorUIFigureSizeChanged(app, event)
            position = app.MinimizatorUIFigure.Position;
            width = position(3);
            height = position(4);
            app.InfoPanel.Position = [0, 0, 300, height + 1];
            app.TabGroup.Position = [300, 0, width - 300 + 1, height + 1];
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            app.functionName = value;
            
            % set start point
            setStartPoint(app, value);
            
            % clear all when function is changed
            app.functionShown = false;
        end

        % Value changed function: X1Spinner
        function X1SpinnerValueChanged(app, event)
            value = app.X1Spinner.Value;
            app.startX1 = value;
            app.functionShown = false;
        end

        % Value changed function: X2Spinner
        function X2SpinnerValueChanged(app, event)
            value = app.X2Spinner.Value;
            app.startX2 = value;
            app.functionShown = false;
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            % call adapt
            adapt(app);
        end

        % Value changed function: GroupDropDown
        function GroupDropDownValueChanged(app, event)
            value = app.GroupDropDown.Value;
            setMethodsFromGroup(app, value);
            if (app.defaultMode)
                setLineSearch(app, app.GroupDropDown.Value, app.MethodDropDown.Value);
            end
            % hide line search if trust region
            if strcmp(value, 'TrustRegion')
                app.LineSearchPanel.Visible = 'off';
            else
                app.LineSearchPanel.Visible = 'on';
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if (app.defaultMode)
                setLineSearch(app, app.GroupDropDown.Value, app.MethodDropDown.Value);
            end
        end

        % Value changed function: LineDropDown
        function LineDropDownValueChanged(app, event)
            value = app.LineDropDown.Value;
            if (app.defaultMode)
                setLineSearchParams(app, value);
            end
        end

        % Value changed function: EnableLine
        function EnableLineValueChanged(app, event)
            value = app.EnableLine.Value;
            state = 'off';
            if (value)
               state = 'on';
            end
            children = app.EnableLine.Parent.Children;
            sz = size(children);
            n = sz(1);
            for i = 1:n
               children(i).Enable = state; 
            end
            app.EnableLine.Enable = 'on';
        end

        % Value changed function: EnableStop
        function EnableStopValueChanged(app, event)
            value = app.EnableStop.Value;
            state = 'off';
            if (value)
               state = 'on';
            end
            children = app.EnableStop.Parent.Children;
            sz = size(children);
            n = sz(1);
            for i = 1:n
               children(i).Enable = state; 
            end
            app.EnableStop.Enable = 'on';
        end

        % Cell edit callback: ResTable
        function ResTableCellEdit(app, event)
            % show or delete
            row = event.Indices(1);
            col = event.Indices(2);
            if col == 2
                % delete from table
                remFromTable(app, row);
                % delete from plot
                clearPlot(app, row);
            elseif col == 1
                % show or hide
                hideFromPlot(app, row, event.NewData);
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            % clear table data
            clearAll(app);
        end

        % Value changed function: IterationsSpinner, Spinner
        function StepSpinnerValueChanged(app, event)
            value = event.Value;
            % sync both spinners
            if (event.Source == app.Spinner)
                app.IterationsSpinner.Value = value;
            else
                app.Spinner.Value = value;
            end
            % update sliders
            app.SliderIter.Value = value;
            app.SliderIter3D.Value = value;
            % update plot
            redrawPlots(app, value + 1);
        end

        % Value changing function: SliderIter, SliderIter3D
        function SliderIterValueChanging(app, event)
            value = event.Value;
            % sync both sliders
            if (event.Source == app.SliderIter)
                app.SliderIter3D.Value = value;
            else
                app.SliderIter.Value = value;
            end
            % update spinner
            app.Spinner.Value = round(value);
            app.IterationsSpinner.Value = round(value);
            % update plot
            redrawPlots(app, round(value) + 1);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % resets plot zoom
            resetZoom(app);
        end

        % Selection changed function: ButtonGroup
        function ManipulateButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Zoom')
               pan(app.MainAxes, 'off');
               zoom(app.MainAxes, 'on');
            else
               zoom(app.MainAxes, 'off');
               pan(app.MainAxes, 'on');
            end
        end

        % Value changed function: DefaultParametersModeCheckBox
        function DefaultParametersModeCheckBoxValueChanged(app, event)
            value = app.DefaultParametersModeCheckBox.Value;
            app.defaultMode = value;
            % set default line search and params
            if (value)
                setLineSearch(app, app.GroupDropDown.Value, app.MethodDropDown.Value);
            end
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            value = app.MaxSpinner.Value;
            setSlider(app);
            redrawPlots(app, value);
        end

        % Value changed function: TestSpinner
        function TestSpinnerValueChanged(app, event)
            value = app.TestSpinner.Value;
            app.contNumber = value;
            adapt(app);
        end

        % Value changed function: ContoursSlider
        function ContoursSliderValueChanged(app, event)
            value = app.ContoursSlider.Value;
            app.contNumber = value;
            adapt(app);
        end

        % Value changed function: StepSpinner
        function StepSpinnerValueChanged2(app, event)
            value = app.StepSpinner.Value;
            app.Spinner.Step = value;
            app.IterationsSpinner.Step = value;
        end

        % Value changed function: ContourColorsCheckBox
        function ContourColorsCheckBoxValueChanged(app, event)
            value = app.ContourColorsCheckBox.Value;
            if (value)
               colormap(app.MainAxes, parula); 
               set(app.contMain ,'LineColor',[0 0 0]/255);
            else
               colormap(app.MainAxes, 1 - (1 - flipud(bone))*0.3 );
               set(app.contMain ,'LineColor',[100 100 100]/255);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MinimizatorUIFigure = uifigure;
            app.MinimizatorUIFigure.Name = 'Minimizator';
            app.MinimizatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @MinimizatorUIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.MinimizatorUIFigure);
            app.tabMain = uitab(app.TabGroup);
            app.tabMain.Title = 'Full';
            app.MainAxes = uiaxes(app.tabMain);
            app.DimAxes = uiaxes(app.tabMain);
            app.DimAxes.XTickLabel = '';
            app.DimAxes.YTickLabel = '';
            app.DimAxes.ZTickLabel = '';
            app.ResTable = uitable(app.tabMain);
            app.ResTable.ColumnName = {'Show'; 'Delete'; 'Method'; 'Group'; 'Line Search'; 'Fmin'; 'Xmin'; 'Gradient'; 'Iters'; 'CPU'; 'Func'; 'Grad'; 'Hess'};
            app.ResTable.RowName = {};
            app.ResTable.ColumnEditable = [true true false false false false false false false false false false false];
            app.ResTable.CellEditCallback = createCallbackFcn(app, @ResTableCellEdit, true);
            app.IterationsPanel = uipanel(app.tabMain);
            app.IterationsPanel.Title = 'Iterations';
            app.Spinner = uispinner(app.IterationsPanel);
            app.Spinner.Step = 50;
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerValueChanged, true);
            app.SliderIter = uislider(app.IterationsPanel);
            app.SliderIter.MajorTicks = [];
            app.SliderIter.MajorTickLabels = {};
            app.SliderIter.ValueChangingFcn = createCallbackFcn(app, @SliderIterValueChanging, true);
            app.SliderIter.MinorTicks = [];
            app.MaxSpinnerLabel = uilabel(app.IterationsPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.IterationsPanel);
            app.MaxSpinner.Limits = [0 Inf];
            app.MaxSpinner.RoundFractionalValues = 'on';
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 50;
            app.StepSpinnerLabel = uilabel(app.IterationsPanel);
            app.StepSpinnerLabel.Text = 'Step';
            app.StepSpinner = uispinner(app.IterationsPanel);
            app.StepSpinner.Limits = [1 1000];
            app.StepSpinner.RoundFractionalValues = 'on';
            app.StepSpinner.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerValueChanged2, true);
            app.StepSpinner.Value = 1;
            app.ManipulatePanel = uipanel(app.tabMain);
            app.ManipulatePanel.Title = 'Manipulate';
            app.ResetButton = uibutton(app.ManipulatePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ButtonGroup = uibuttongroup(app.ManipulatePanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ManipulateButtonGroupSelectionChanged, true);
            app.ZoomButton = uitogglebutton(app.ButtonGroup);
            app.ZoomButton.Text = 'Zoom';
            app.ZoomButton.Value = true;
            app.PanButton = uitogglebutton(app.ButtonGroup);
            app.PanButton.Text = 'Pan';
            app.ContourColorsCheckBox = uicheckbox(app.ManipulatePanel);
            app.ContourColorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourColorsCheckBoxValueChanged, true);
            app.ContourColorsCheckBox.Text = ' Contour Colors';
            app.ContourColorsCheckBox.Value = true;
            app.ClearAllButton = uibutton(app.tabMain, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ContoursSliderLabel = uilabel(app.tabMain);
            app.ContoursSliderLabel.Text = 'Contours';
            app.ContoursSlider = uislider(app.tabMain);
            app.ContoursSlider.Limits = [-1 1];
            app.ContoursSlider.MajorTicks = [];
            app.ContoursSlider.MajorTickLabels = {};
            app.ContoursSlider.ValueChangedFcn = createCallbackFcn(app, @ContoursSliderValueChanged, true);
            app.ContoursSlider.MinorTicks = [];
            app.Info = uilabel(app.tabMain);
            app.Label = uilabel(app.tabMain);
            app.Label.Text = '';
            app.TestSpinner = uispinner(app.tabMain);
            app.TestSpinner.ValueChangedFcn = createCallbackFcn(app, @TestSpinnerValueChanged, true);
            app.TestSpinner.Visible = 'off';
            app.tab3D = uitab(app.TabGroup);
            app.tab3D.Title = '3D';
            app.Axes3D = uiaxes(app.tab3D);
            app.SliderIter3D = uislider(app.tab3D);
            app.SliderIter3D.MajorTicks = [];
            app.SliderIter3D.MajorTickLabels = {};
            app.SliderIter3D.ValueChangingFcn = createCallbackFcn(app, @SliderIterValueChanging, true);
            app.SliderIter3D.MinorTicks = [];
            app.IterationsSpinnerLabel = uilabel(app.tab3D);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.tab3D);
            app.IterationsSpinner.RoundFractionalValues = 'on';
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerValueChanged, true);
            app.InfoPanel = uipanel(app.MinimizatorUIFigure);
            app.funcPanel = uipanel(app.InfoPanel);
            app.FunctionDropDownLabel = uilabel(app.funcPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.funcPanel);
            app.FunctionDropDown.Items = {'Rosen', 'Raydan1', 'Raydan2', 'Himmelblau'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'Rosen';
            app.StartPointPanel = uipanel(app.funcPanel);
            app.StartPointPanel.Title = 'Start Point';
            app.X1SpinnerLabel = uilabel(app.StartPointPanel);
            app.X1SpinnerLabel.Text = 'X1';
            app.X1Spinner = uispinner(app.StartPointPanel);
            app.X1Spinner.ValueChangedFcn = createCallbackFcn(app, @X1SpinnerValueChanged, true);
            app.X2SpinnerLabel = uilabel(app.StartPointPanel);
            app.X2SpinnerLabel.Text = 'X2';
            app.X2Spinner = uispinner(app.StartPointPanel);
            app.X2Spinner.ValueChangedFcn = createCallbackFcn(app, @X2SpinnerValueChanged, true);
            app.DefaultParametersModeCheckBox = uicheckbox(app.funcPanel);
            app.DefaultParametersModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultParametersModeCheckBoxValueChanged, true);
            app.DefaultParametersModeCheckBox.Text = 'Default Parameters Mode';
            app.DefaultParametersModeCheckBox.Value = true;
            app.Panel = uipanel(app.InfoPanel);
            app.CalculateMinimumButton = uibutton(app.Panel, 'push');
            app.CalculateMinimumButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMinimumButtonPushed, true);
            app.CalculateMinimumButton.Text = 'Calculate Minimum';
            app.LineSearchPanel = uipanel(app.Panel);
            app.LineSearchPanel.Title = 'Line Search';
            app.EnableLine = uicheckbox(app.LineSearchPanel);
            app.EnableLine.ValueChangedFcn = createCallbackFcn(app, @EnableLineValueChanged, true);
            app.EnableLine.Text = 'Enable';
            app.MethodDropDown_2Label = uilabel(app.LineSearchPanel);
            app.MethodDropDown_2Label.Enable = 'off';
            app.MethodDropDown_2Label.Text = 'Method';
            app.LineDropDown = uidropdown(app.LineSearchPanel);
            app.LineDropDown.ValueChangedFcn = createCallbackFcn(app, @LineDropDownValueChanged, true);
            app.LineDropDown.Enable = 'off';
            app.EditField_2Label = uilabel(app.LineSearchPanel);
            app.EditField_2Label.Enable = 'off';
            app.EditField_2Label.Text = 'β';
            app.BetaEditField = uieditfield(app.LineSearchPanel, 'numeric');
            app.BetaEditField.Enable = 'off';
            app.BetaEditField.Value = 0.8;
            app.startEditFieldLabel = uilabel(app.LineSearchPanel);
            app.startEditFieldLabel.Enable = 'off';
            app.startEditFieldLabel.Text = 'start';
            app.StartEditField = uieditfield(app.LineSearchPanel, 'numeric');
            app.StartEditField.Enable = 'off';
            app.StartEditField.Value = 1;
            app.MEditFieldLabel = uilabel(app.LineSearchPanel);
            app.MEditFieldLabel.Enable = 'off';
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.LineSearchPanel, 'numeric');
            app.MEditField.Enable = 'off';
            app.MEditField.Value = 10;
            app.EditField_3Label = uilabel(app.LineSearchPanel);
            app.EditField_3Label.Enable = 'off';
            app.EditField_3Label.Text = 'σ';
            app.SigmaEditField = uieditfield(app.LineSearchPanel, 'numeric');
            app.SigmaEditField.Enable = 'off';
            app.SigmaEditField.Value = 0.1;
            app.EditFieldLabel = uilabel(app.LineSearchPanel);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = 'ρ';
            app.RhoEditField = uieditfield(app.LineSearchPanel, 'numeric');
            app.RhoEditField.Enable = 'off';
            app.RhoEditField.Value = 0.8;
            app.StopConditionsPanel = uipanel(app.Panel);
            app.StopConditionsPanel.Title = 'Stop Conditions';
            app.EnableStop = uicheckbox(app.StopConditionsPanel);
            app.EnableStop.ValueChangedFcn = createCallbackFcn(app, @EnableStopValueChanged, true);
            app.EnableStop.Text = 'Enable';
            app.IterationsEditFieldLabel = uilabel(app.StopConditionsPanel);
            app.IterationsEditFieldLabel.Enable = 'off';
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.StopConditionsPanel, 'numeric');
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.Enable = 'off';
            app.IterationsEditField.Value = 10000;
            app.EpsilonEditFieldLabel = uilabel(app.StopConditionsPanel);
            app.EpsilonEditFieldLabel.Enable = 'off';
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.StopConditionsPanel, 'numeric');
            app.EpsilonEditField.Enable = 'off';
            app.EpsilonEditField.Value = 1e-06;
            app.PrecisionEditFieldLabel = uilabel(app.StopConditionsPanel);
            app.PrecisionEditFieldLabel.Enable = 'off';
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.StopConditionsPanel, 'numeric');
            app.PrecisionEditField.Enable = 'off';
            app.PrecisionEditField.Value = 1e-16;
            app.GroupDropDownLabel = uilabel(app.Panel);
            app.GroupDropDownLabel.Text = 'Group';
            app.GroupDropDown = uidropdown(app.Panel);
            app.GroupDropDown.Items = {'BFGSMethodPlot', 'inexactLineSearchPlot', 'GoldsteinPriceMethodPlot', 'GoldfeldMethodPlot', 'DFPMethodPlot'};
            app.GroupDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupDropDownValueChanged, true);
            app.GroupDropDown.Value = 'BFGSMethodPlot';
            app.MethodDropDown_12Label = uilabel(app.Panel);
            app.MethodDropDown_12Label.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Armijo', 'Goldstein', 'Wolfe', 'StrongWolfe'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Wolfe';
        end
    end

    methods (Access = public)

        % Construct app
        function app = twoDimPlot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MinimizatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @onCreate)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MinimizatorUIFigure)
        end
    end
end